classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ComputeButton          matlab.ui.control.Button
        EdgeTypeDropDown       matlab.ui.control.DropDown
        EdgeTypeDropDownLabel  matlab.ui.control.Label
        SelectImageFileButton  matlab.ui.control.Button
        MenuDropDown           matlab.ui.control.DropDown
        MenuDropDownLabel      matlab.ui.control.Label
        UIAxesResult           matlab.ui.control.UIAxes
        UIAxesOriginal         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % image array
        menu
        type
    end
    
    methods (Access = private)
        
        function compute(app)
            if isempty(app.image)
                return
            end
            if isempty(app.menu)
                app.menu = app.MenuDropDown.Value;
            end
            if isempty(app.type)
                app.type = lower(app.EdgeTypeDropDown.Value);
            end

            switch app.menu
                case 'Edge Detection'
                    edgeImage = detectEdge(app.image, app.type);
                    imshow(edgeImage, 'Parent', app.UIAxesResult);
                case 'Object Detection'
                    edgeImage = detectEdge(app.image, app.type);
                    detectedObjects = detectObject(edgeImage, app.image);
                    imshow(detectedObjects, 'Parent', app.UIAxesResult);
                case 'Line Detection'
                    detectLines(app.image, true, app.type, app.UIAxesResult);
            end
            figure(app.UIFigure);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageFileButton
        function SelectImageFileButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp;*.tif;*.jpeg', 'Image Files'}, 'Select an Image');
            if isequal(filename, 0)
                return;
            end

            fullpath = fullfile(pathname, filename);
            app.image = imread(fullpath);
            imshow(app.image, 'Parent', app.UIAxesOriginal);
            figure(app.UIFigure);
        end

        % Value changed function: MenuDropDown
        function MenuDropDownValueChanged(app, event)
            value = app.MenuDropDown.Value;
            app.menu = value;
        end

        % Value changed function: EdgeTypeDropDown
        function EdgeTypeDropDownValueChanged(app, event)
            value = lower(app.EdgeTypeDropDown.Value);
            app.type = value;
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            compute(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesOriginal, 'Original Image')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesOriginal.ZTick = [];
            app.UIAxesResult = uiaxes(app.UIFigure);
            title(app.UIAxesResult, 'Detection Result')
            app.UIAxesResult.XTick = [];
            app.UIAxesResult.YTick = [];
            app.UIAxesResult.ZTick = [];
            app.MenuDropDownLabel = uilabel(app.UIFigure);
            app.MenuDropDownLabel.Text = 'Menu';
            app.MenuDropDown = uidropdown(app.UIFigure);
            app.MenuDropDown.Items = {'Edge Detection', 'Object Detection', 'Line Detection'};
            app.MenuDropDown.ValueChangedFcn = createCallbackFcn(app, @MenuDropDownValueChanged, true);
            app.MenuDropDown.Value = 'Edge Detection';
            app.SelectImageFileButton = uibutton(app.UIFigure, 'push');
            app.SelectImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageFileButtonPushed, true);
            app.SelectImageFileButton.Text = 'Select Image File';
            app.EdgeTypeDropDownLabel = uilabel(app.UIFigure);
            app.EdgeTypeDropDownLabel.Text = 'Edge Type';
            app.EdgeTypeDropDown = uidropdown(app.UIFigure);
            app.EdgeTypeDropDown.Items = {'Canny', 'Sobel', 'Prewitt', 'Roberts', 'Laplace', 'LoG'};
            app.EdgeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeTypeDropDownValueChanged, true);
            app.EdgeTypeDropDown.Value = 'Canny';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end